classdef force_biometrics_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                   matlab.ui.Figure
        popupmenu_level           matlab.ui.control.DropDown
        text4                     matlab.ui.control.Label
        edit_level                matlab.ui.control.EditField
        edit_com                  matlab.ui.control.EditField
        pushbutton_Trigger        matlab.ui.control.Button
        pushbutton_Stop           matlab.ui.control.Button
        pushbutton_Start          matlab.ui.control.Button
        popupmenu_protocol        matlab.ui.control.DropDown
        text3                     matlab.ui.control.Label
        text2                     matlab.ui.control.Label
        edit_MVCvalue             matlab.ui.control.EditField
        pushbutton_MVCTest        matlab.ui.control.Button
        pushbutton_Configuration  matlab.ui.control.Button
        axes_force                matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.figure1.DeleteFcn = createCallbackFcn(app, @figure1_DeleteFcn, true);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function force_biometrics_OpeningFcn(app, varargin)
            % --- Executes just before force_biometrics is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to force_biometrics (see VARARGIN)
            
            % Choose default command line output for force_biometrics
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: edit_level
        function edit_level_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            popupmenu_protocol_Callback(hObject, eventdata, handles);
        end

        % Callback function
        function figure1_DeleteFcn(app, event)
            % --- Executes during object deletion, before destroying properties.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            try
                close(handles.fig_sub);
            catch
            end
            try
                delete(handles.serialport);
            catch
            end
        end

        % Value changed function: popupmenu_level
        function popupmenu_level_Callback(app, event)
            % --- Executes on selection change in popupmenu_level.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            popupmenu_protocol_Callback(hObject, eventdata, handles);
        end

        % Value changed function: popupmenu_protocol
        function popupmenu_protocol_Callback(app, event)
            % --- Executes on selection change in popupmenu_protocol.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            switch handles.popupmenu_protocol.Value
                case 1
                    handles.order = [ones(1,handles.subtrial),ones(1,handles.subtrial)*2,ones(1,handles.subtrial)*3,ones(1,handles.subtrial)*4];
                    handles.order = handles.order(randperm(length(handles.order)));
                    handles.trapx = cell(1,length(handles.order));
                    handles.trapy = cell(1,length(handles.order));
                    handles.taskover = zeros(1,length(handles.order));
                    handles.trialL = handles.T*ones(1,length(handles.order));
                    for i = 1:length(handles.order)
                        if handles.order(i) == 1 % slow 20% MVC
                            tmp = 20;
                            handles.trapx{i} = [0 2 5 7.5 7.5];
                            handles.trapy{i} = [0 0 tmp tmp 0];
                            handles.taskover(i)=handles.trapx{i}(end);
                            %         handles.trialL(i)= handles.taskover(i)+handles.restp(i);
            
                        elseif handles.order(i) == 2 % slow 60% MVC
                            tmp = 60;
                            handles.trapx{i} =  [0 2 11 13.5 13.5];
                            handles.trapy{i} = [0 0 tmp tmp 0];
                            handles.taskover(i)=handles.trapx{i}(end);
                            %         handles.trialL(i)= handles.taskover(i)+handles.restp(i);
                        elseif handles.order(i) == 3 % fast 20% MVC
                            tmp = 20;
                            handles.trapx{i} = [0 2 3 5.5 5.5];
                            handles.trapy{i} = [0 0 tmp tmp 0];
                            handles.taskover(i)=handles.trapx{i}(end);
                            %         handles.trialL(i)= handles.taskover(i)+handles.restp(i);
                        elseif handles.order(i) == 4 % fast 60% MVC
                            tmp = 60;
                            handles.trapx{i} = [0 2 5 7.5 7.5];
                            handles.trapy{i} = [0 0 tmp tmp 0];
                            handles.taskover(i)=handles.trapx{i}(end);
                            %         handles.trialL(i)= handles.taskover(i)+handles.restp(i);
                        end
                    end
                    set(handles.force_ref,'xdata',handles.trapx{handles.trialnum},'ydata',handles.trapy{handles.trialnum});
                    set(handles.axes_force,'xlim',[0,handles.T],'ylim',[-10,70],'ygrid','on');
                    set(handles.force_ref_sub,'xdata',handles.trapx{handles.trialnum},'ydata',handles.trapy{handles.trialnum});
                    set(handles.axes_sub,'xlim',[0,handles.T],'ylim',[-10,70],'ygrid','on');
            
                    forceOrder = handles.order;
                    forceRef{1} = handles.trapx;
                    forceRef{2} = handles.trapy;
                    save(['forceProtocol_' num2str(handles.FileCounter) '.mat'],"forceOrder","forceRef");
                case 2
                    tmp = str2num(handles.edit_level.String);
                    handles.trapx{1} = [0 5 10 20 25 30];
                    handles.trapy{1} = [0 0 tmp tmp 0 0];
                    set(handles.force_ref,'xdata',handles.trapx{handles.trialnum},'ydata',handles.trapy{handles.trialnum});
                    set(handles.axes_force,'xlim',[0,handles.T],'ylim',[-10,tmp+10],'ygrid','on');
                    set(handles.force_ref_sub,'xdata',handles.trapx{handles.trialnum},'ydata',handles.trapy{handles.trialnum});
                    set(handles.axes_sub,'xlim',[0,handles.T],'ylim',[-10,tmp+10],'ygrid','on');
                case 3
                    tmp = str2num(handles.edit_level.String);
                    handles.trapx{1} = [0 2.5 5 15 17.5 20];
                    handles.trapy{1} = [0 0 tmp tmp 0 0];
                    set(handles.force_ref,'xdata',handles.trapx{handles.trialnum},'ydata',handles.trapy{handles.trialnum});
                    set(handles.axes_force,'xlim',[0,handles.T],'ylim',[-10,tmp+10],'ygrid','on');
                    set(handles.force_ref_sub,'xdata',handles.trapx{handles.trialnum},'ydata',handles.trapy{handles.trialnum});
                    set(handles.axes_sub,'xlim',[0,handles.T],'ylim',[-10,tmp+10],'ygrid','on');
                case 4
                    handles.trialnummax = 100;
                    handles.trapx = cell(1,handles.trialnummax);
                    handles.trapy = cell(1,handles.trialnummax);
                    % tmp = str2num(handles.edit_level.String);
                    % tmpind = handles.popupmenu_level.Value;
                    % tmp = str2num(handles.popupmenu_level.String{tmpind});
                    tmp = str2num(handles.edit_level.String);
                    handles.trapx{1} = [0 handles.T];
                    handles.trapy{1} = [tmp tmp];
                    set(handles.force_ref,'xdata',handles.trapx{handles.trialnum},'ydata',handles.trapy{handles.trialnum});
                    set(handles.axes_force,'xlim',[0,handles.T],'ylim',[-10,tmp+10],'ygrid','on');
                    set(handles.force_ref_sub,'xdata',handles.trapx{handles.trialnum},'ydata',handles.trapy{handles.trialnum});
                    set(handles.axes_sub,'xlim',[0,handles.T],'ylim',[-10,tmp+10],'ygrid','on');
                    handles.trapx(2:end) = handles.trapx(1);
                    handles.trapy(2:end) = handles.trapy(1);
            end
            guidata(hObject,handles);
        end

        % Button pushed function: pushbutton_Configuration
        function pushbutton_Configuration_Callback(app, event)
            % --- Executes on button press in pushbutton_Configuration.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % configuration for biometric
            % addpath('C:\Users\Lenovo\Documents\MATLAB64'); % will need to be changed
            if ~libisloaded('OnLineInterface64')  % only load if not already loaded
                [notfound,warnings]=loadlibrary('OnLineInterface64.dll', 'OnLineInterface.h');
            end
            % libfunctionsview('OnLineInterface64')	% Open a window to show the functions available in the library
            feature('COM_SafeArraySingleDim', 1);   % only use single dimension SafeArrays
            feature('COM_PassSafeArrayByRef', 1);
            int32 ch;
            ch = 18;
            handles.ch = ch;	% will need to be changed to match the sensor channel
            handles.values = libstruct('tagSAFEARRAY');
            handles.fsamp = double(getSampleRate(ch));
            
            
            handles.BIAS = 0;
            handles.FileCounter = 0;
            handles.FileCounter_mvc = 0;
            handles.MVCnum=1;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % handles.sessionnum = input('Which session is this experiment? (0/1), input 0 if just for test!\');
            handles.comnum=3;
            handles.val=0;
            handles.subtrial=10;
            % handles.order = [ones(1,handles.subtrial),ones(1,handles.subtrial)*2,ones(1,handles.subtrial)*3,ones(1,handles.subtrial)*4];
            handles.order = [1];
            handles.trialnum = 1;
            handles.trialnummax = length(handles.order);
            
            handles.RefreshRate = 0.1; % s
            handles.T = 15; % s force screen time
            
            handles.force = plot(handles.axes_force,0,0);
            hold on;
            handles.force_ref = plot(handles.axes_force,0,0,'linewidth',2,'Color','r');
            handles.axes_force.XLim = [0,handles.T];
            % set(handles.axes_force,'xlim',[0,handles.T]);
            p = get(0,'monitorpositions');
            
            set(handles.fig_sub,'visible','off');
            handles.axes_sub = axes;
            hold on;
            handles.force_sub = plot(handles.axes_sub,0,0);
            handles.force_ref_sub = plot(handles.axes_sub,0,0,'linewidth',5,'Color','r');
            axis off;
            guidata(hObject,handles);
        end

        % Button pushed function: pushbutton_MVCTest
        function pushbutton_MVCTest_Callback(app, event)
            % --- Executes on button press in pushbutton_MVCTest.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            set(handles.pushbutton_MVCTest,'BackgroundColor',[1,0,0]);
            global para
            para.flag_record = 1;
            
            plotData = [];
            numberOfValues = getData(handles.ch, getSampleRate(handles.ch), 50000, handles.values);
            pause(0.05)
            % numberOfValues = getData(handles.ch, handles.fsamp, 1000, handles.values)
            % handles.values.pvData
            handles.edit_MVCvalue.String = '-inf';
            while para.flag_record
                numberOfValues = getData(handles.ch, handles.fsamp, 1000, handles.values);
                tmpData = -double(handles.values.pvData);
                mean(tmpData)
                if mean(tmpData)>str2double(handles.edit_MVCvalue.String)
                    handles.edit_MVCvalue.String = num2str(round(mean(tmpData)));
                end
            %     values.pvData
                plotData = [plotData,tmpData];
                set(handles.force,'xdata',[1:length(plotData)]/handles.fsamp,'ydata',plotData,'linewidth',2,'color','k');
                handles.mvcData = plotData;
                numT = ceil(length(plotData)/handles.fsamp/handles.T);
                set(handles.axes_force,'xlim',(numT-1)*handles.T+[0,handles.T],'ylim',[-3600,max(plotData)*1.2]);
                guidata(hObject,handles);
                pause(0.1);
            end
        end

        % Button pushed function: pushbutton_Start
        function pushbutton_Start_Callback(app, event)
            % --- Executes on button press in pushbutton_Start.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            set(handles.pushbutton_Start,'BackgroundColor',[1,0,0]);
            global para
            para.flag_record = 1;
            
            handles.forceData = [];
            numberOfValues = getData(handles.ch, getSampleRate(handles.ch), 50000, handles.values);
            pause(0.01)
            % tic;
            numT = 1;
            set(handles.axes_force,'xlim',(numT-1)*handles.T+[0,handles.T]);
            set(handles.force_ref,'xdata',handles.trapx{numT}+(numT-1)*handles.T,'ydata',handles.trapy{numT});
            if handles.pushbutton_Trigger.BackgroundColor == [0,1,0]
                write(handles.serialport,'1','char');
            end
            while para.flag_record
                numberOfValues = getData(handles.ch, handles.fsamp, 1000, handles.values);
                tmpData = -double(handles.values.pvData);
                handles.forceData = [handles.forceData,tmpData];
                tmpForce = (mean(tmpData)-handles.forceBias)/(handles.mvcPoint-handles.forceBias)*100;
            %     values.pvData
            %     set(handles.force,'xdata',length(handles.forceData)/handles.fsamp,'ydata',tmpForce,'MarkerFaceColor','k','MarkerEdgecolor','k','Marker','O','Markersize',20);
            %     handles.mvcData = plotData;
                currentT = length(handles.forceData)/handles.fsamp;
                if ceil(currentT/handles.T)>numT
                    numT = ceil(currentT/handles.T);
                    % if handles.pushbutton_Trigger.BackgroundColor == [0,1,0]
                    %     write(handles.serialport,'1','char');
                    % end
                    if numT>handles.trialnummax
                        if handles.pushbutton_Trigger.BackgroundColor == [0,1,0]
                            write(handles.serialport,'1','char');
                        end
                        forceData_raw = handles.forceData;
                        forceData = (forceData_raw-handles.forceBias)/(handles.mvcPoint-handles.forceBias)*100;
                        fsamp = handles.fsamp;
                        handles.FileCounter = handles.FileCounter+1;
                        save(['forceData_' num2str(handles.FileCounter_mvc) '_' num2str(handles.FileCounter) '.mat'],"forceData","forceData_raw","fsamp");
                        para.flag_record = 0;
                        handles.pushbutton_Start.BackgroundColor = [1,1,1];
                        fprintf(['Session ' num2str(handles.FileCounter_mvc) ', force ' num2str(handles.FileCounter) ' completed!\n'])
            
                        guidata(hObject,handles);
                        break;
                    end
                    set(handles.force_ref,'xdata',handles.trapx{numT}+(numT-1)*handles.T,'ydata',handles.trapy{numT});
                    set(handles.axes_force,'xlim',(numT-1)*handles.T+[0,handles.T]);
                    set(handles.force_ref_sub,'xdata',handles.trapx{numT}+(numT-1)*handles.T,'ydata',handles.trapy{numT});
                    set(handles.axes_sub,'xlim',(numT-1)*handles.T+[0,handles.T]);
                end
                set(handles.force,'xdata',currentT,'ydata',tmpForce,'MarkerFaceColor','k','MarkerEdgecolor','k','Marker','O','Markersize',20);
            %     if rem(currentT,15)<handles.taskover(numT)+1
                    set(handles.force_sub,'xdata',currentT,'ydata',tmpForce,'MarkerFaceColor','k','MarkerEdgecolor','k','Marker','O','Markersize',20);
            %     else
            %         set(handles.force,'xdata',currentT,'ydata',-10);
            %         set(handles.force_sub,'xdata',currentT,'ydata',-20);
            %     end
                guidata(hObject,handles);
                pause(0.1);
            end
        end

        % Button pushed function: pushbutton_Stop
        function pushbutton_Stop_Callback(app, event)
            % --- Executes on button press in pushbutton_Stop.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global para
            para.flag_record = 0;
            if handles.pushbutton_MVCTest.BackgroundColor == [1,0,0]
                mvcData = handles.mvcData;
                handles.FileCounter_mvc = handles.FileCounter_mvc+1;
                fsamp = handles.fsamp;
                save(['MVCdata_' num2str(handles.FileCounter_mvc) '.mat'],'mvcData',"fsamp");
                handles.pushbutton_MVCTest.BackgroundColor = [1,1,1];
                handles.mvcPoint = max(handles.mvcData);
                % handles.forceBias = min(handles.mvcData);
                handles.forceBias = prctile(handles.mvcData,10);
                handles.FileCounter = 0;
            end
            if handles.pushbutton_Start.BackgroundColor == [1,0,0]
                if handles.pushbutton_Trigger.BackgroundColor == [0,1,0]
                    write(handles.serialport,'1','char');
                end
                forceData_raw = handles.forceData;
                fsamp = handles.fsamp;
                forceData = (forceData_raw-handles.forceBias)/(handles.mvcPoint-handles.forceBias)*100;
                handles.FileCounter = handles.FileCounter+1;
                save(['forceData_' num2str(handles.FileCounter_mvc) '_'  num2str(handles.FileCounter) '.mat'],"forceData","forceData_raw","fsamp");
                handles.pushbutton_Start.BackgroundColor = [1,1,1];
                fprintf(['Session ' num2str(handles.FileCounter_mvc) ', force ' num2str(handles.FileCounter) ' stopped mannually!\n'])
            
            end
            guidata(hObject,handles);
        end

        % Button pushed function: pushbutton_Trigger
        function pushbutton_Trigger_Callback(app, event)
            % --- Executes on button press in pushbutton_Trigger.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if handles.pushbutton_Trigger.BackgroundColor == [1,1,1]
                handles.serialport = serialport(handles.edit_com.String,2000000);
                handles.pushbutton_Trigger.BackgroundColor = [0,1,0];
                fprintf('Serialport opened!\n')
            else
                delete(handles.serialport);
                handles.pushbutton_Trigger.BackgroundColor = [1,1,1];
                fprintf('Serialport deleted!\n')
            end
            guidata(hObject,handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'force_biometrics';
            app.figure1.Tag = 'figure1';
            app.axes_force = uiaxes(app.figure1);
            app.axes_force.Tag = 'axes_force';
            app.pushbutton_Configuration = uibutton(app.figure1, 'push');
            app.pushbutton_Configuration.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Configuration_Callback, true);
            app.pushbutton_Configuration.Tag = 'pushbutton_Configuration';
            app.pushbutton_Configuration.Text = 'Configuration';
            app.pushbutton_MVCTest = uibutton(app.figure1, 'push');
            app.pushbutton_MVCTest.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_MVCTest_Callback, true);
            app.pushbutton_MVCTest.Tag = 'pushbutton_MVCTest';
            app.pushbutton_MVCTest.Text = 'MVC test';
            app.edit_MVCvalue = uieditfield(app.figure1, 'text');
            app.edit_MVCvalue.Tag = 'edit_MVCvalue';
            app.edit_MVCvalue.Value = '-inf';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'MVC value';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Protocol';
            app.popupmenu_protocol = uidropdown(app.figure1);
            app.popupmenu_protocol.Items = {'random 4', 'trapezoid', 'trapezoid20', 'continuous'};
            app.popupmenu_protocol.ValueChangedFcn = createCallbackFcn(app, @popupmenu_protocol_Callback, true);
            app.popupmenu_protocol.Tag = 'popupmenu_protocol';
            app.popupmenu_protocol.Value = 'random 4';
            app.pushbutton_Start = uibutton(app.figure1, 'push');
            app.pushbutton_Start.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Start_Callback, true);
            app.pushbutton_Start.Tag = 'pushbutton_Start';
            app.pushbutton_Start.Text = 'Start';
            app.pushbutton_Stop = uibutton(app.figure1, 'push');
            app.pushbutton_Stop.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Stop_Callback, true);
            app.pushbutton_Stop.Tag = 'pushbutton_Stop';
            app.pushbutton_Stop.Text = 'Stop';
            app.pushbutton_Trigger = uibutton(app.figure1, 'push');
            app.pushbutton_Trigger.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Trigger_Callback, true);
            app.pushbutton_Trigger.Tag = 'pushbutton_Trigger';
            app.pushbutton_Trigger.Text = 'Trigger';
            app.edit_com = uieditfield(app.figure1, 'text');
            app.edit_com.Tag = 'edit_com';
            app.edit_com.Value = 'com7';
            app.edit_level = uieditfield(app.figure1, 'text');
            app.edit_level.ValueChangedFcn = createCallbackFcn(app, @edit_level_Callback, true);
            app.edit_level.Tag = 'edit_level';
            app.edit_level.Value = '10';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Level';
            app.popupmenu_level = uidropdown(app.figure1);
            app.popupmenu_level.Items = {'10', '20', '30', '40', '50'};
            app.popupmenu_level.ValueChangedFcn = createCallbackFcn(app, @popupmenu_level_Callback, true);
            app.popupmenu_level.Tag = 'popupmenu_level';
            app.popupmenu_level.Value = '10';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = force_biometrics_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)force_biometrics_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end